classdef TheFishDriver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DetectionTab                    matlab.ui.container.Tab
        DetectionParametersPanel        matlab.ui.container.Panel
        SampleDurationsecondsEditFieldLabel  matlab.ui.control.Label
        SampleDuration                  matlab.ui.control.NumericEditField
        NoiseThresholdLabel             matlab.ui.control.Label
        NoiseThreshold                  matlab.ui.control.NumericEditField
        PowerLawExponentvLabel          matlab.ui.control.Label
        PowerLawExponent                matlab.ui.control.NumericEditField
        AudioFileswavPanel              matlab.ui.container.Panel
        NumberofChannelsEditFieldLabel  matlab.ui.control.Label
        NumberofChannels                matlab.ui.control.NumericEditField
        SelectAudioFileDirectoryButton  matlab.ui.control.Button
        InputDirectoryLocationLabel     matlab.ui.control.Label
        PlottingParametersPanel         matlab.ui.container.Panel
        FFTLengthEditFieldLabel         matlab.ui.control.Label
        FFTLength                       matlab.ui.control.NumericEditField
        PercentOverlapEditFieldLabel    matlab.ui.control.Label
        PercentOverlap                  matlab.ui.control.NumericEditField
        CallParametersPanel             matlab.ui.container.Panel
        MinCallDurationsecondsEditFieldLabel  matlab.ui.control.Label
        MinCallDuration                 matlab.ui.control.NumericEditField
        MaxCallFrequencyHzLabel         matlab.ui.control.Label
        MaxCallFrequency                matlab.ui.control.NumericEditField
        MinCallFrequencyHzLabel         matlab.ui.control.Label
        MinCallFrequency                matlab.ui.control.NumericEditField
        MaxCallDurationsecondsLabel     matlab.ui.control.Label
        MaxCallDuration                 matlab.ui.control.NumericEditField
        RunDetectorButton               matlab.ui.control.Button
        OutputDetectionFilesPanel       matlab.ui.container.Panel
        SelectOutputDetectionFileDirectoryButton  matlab.ui.control.Button
        OutputDirectoryLocationLabel    matlab.ui.control.Label
        TheFishSenseLabel               matlab.ui.control.Label
        ageneralizedpowerlawdetectionalgorithmforfishsoundsLabel  matlab.ui.control.Label
        DAQInformationFilePanel         matlab.ui.container.Panel
        SelectDAQInformationFileButton  matlab.ui.control.Button
        DAQFileLabel                    matlab.ui.control.Label
        ReviewTab                       matlab.ui.container.Tab
        DetectionParametersPanel_2      matlab.ui.container.Panel
        SampleDurationsecondsEditFieldLabel_2  matlab.ui.control.Label
        SampleDuration_2                matlab.ui.control.NumericEditField
        NoiseThresholdLabel_2           matlab.ui.control.Label
        NoiseThreshold_2                matlab.ui.control.NumericEditField
        PowerLawExponentvLabel_2        matlab.ui.control.Label
        PowerLawExponent_2              matlab.ui.control.NumericEditField
        AudioFileswavPanel_2            matlab.ui.container.Panel
        NumberofChannelsEditFieldLabel_2  matlab.ui.control.Label
        NumberofChannels_2              matlab.ui.control.NumericEditField
        SelectAudioFileDirectoryButton_2  matlab.ui.control.Button
        InputDirectoryLocationLabel_2   matlab.ui.control.Label
        PlottingParametersPanel_2       matlab.ui.container.Panel
        FFTLengthEditFieldLabel_2       matlab.ui.control.Label
        FFTLength_2                     matlab.ui.control.NumericEditField
        PercentOverlapEditFieldLabel_2  matlab.ui.control.Label
        PercentOverlap_2                matlab.ui.control.NumericEditField
        CallParametersPanel_2           matlab.ui.container.Panel
        MinCallDurationsecondsEditFieldLabel_2  matlab.ui.control.Label
        MinCallDuration_2               matlab.ui.control.NumericEditField
        MaxCallFrequencyHzLabel_2       matlab.ui.control.Label
        MaxCallFrequency_2              matlab.ui.control.NumericEditField
        MinCallFrequencyHzLabel_2       matlab.ui.control.Label
        MinCallFrequency_2              matlab.ui.control.NumericEditField
        RunDetectorButton_2             matlab.ui.control.Button
        OutputDetectionFilesPanel_2     matlab.ui.container.Panel
        SelectOutputDetectionFileDirectoryButton_2  matlab.ui.control.Button
        OutputDirectoryLocationLabel_2  matlab.ui.control.Label
        TheFishSenseReviewLabel         matlab.ui.control.Label
        manuallyreviewdetectionsfromTheFishSenseLabel  matlab.ui.control.Label
        DAQInformationFilePanel_2       matlab.ui.container.Panel
        SelectDAQInformationFileButton_2  matlab.ui.control.Button
        DAQFileLabel_2                  matlab.ui.control.Label
        TheFishDriverLabel              matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectAudioFileDirectoryButton
        function SelectAudioFileDirectoryButtonPushed(app, event)
            folder = uigetdir;                                  % Calls 'uigetdir' to obtain the directory location from the user
            app.UIFigure.Visible = 'off';                       % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on';
            app.InputDirectoryLocationLabel.Text = folder;      % Sets the label text to be the selected path
        end

        % Button pushed function: 
        % SelectOutputDetectionFileDirectoryButton
        function SelectOutputDetectionFileDirectoryButtonPushed(app, event)
            folder = uigetdir;                                  % Calls 'uigetdir' to obtain the directory location from the user
            app.UIFigure.Visible = 'off';                       % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on';
            app.OutputDirectoryLocationLabel.Text = folder;
        end

        % Button pushed function: SelectDAQInformationFileButton
        function SelectDAQInformationFileButtonPushed(app, event)
            file = uigetfile('*.txt');
            app.UIFigure.Visible = 'off';                       % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on';
            app.DAQFileLabel.Text = file;
        end

        % Button pushed function: RunDetectorButton
        function RunDetectorButtonPushed(app, event)
            app.UIFigure.Visible = 'off';                       % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            
            TheFishSense(app)
            
            app.UIFigure.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DetectionTab = uitab(app.TabGroup);
            app.DetectionTab.Title = 'Detection';
            app.DetectionParametersPanel = uipanel(app.DetectionTab);
            app.DetectionParametersPanel.Title = 'Detection Parameters';
            app.SampleDurationsecondsEditFieldLabel = uilabel(app.DetectionParametersPanel);
            app.SampleDurationsecondsEditFieldLabel.Text = 'Sample Duration (seconds):';
            app.SampleDuration = uieditfield(app.DetectionParametersPanel, 'numeric');
            app.SampleDuration.Limits = [0 Inf];
            app.SampleDuration.Value = 20;
            app.NoiseThresholdLabel = uilabel(app.DetectionParametersPanel);
            app.NoiseThresholdLabel.Text = 'Noise Threshold (%):';
            app.NoiseThreshold = uieditfield(app.DetectionParametersPanel, 'numeric');
            app.NoiseThreshold.Limits = [0 100];
            app.NoiseThreshold.Value = 95;
            app.PowerLawExponentvLabel = uilabel(app.DetectionParametersPanel);
            app.PowerLawExponentvLabel.Text = 'Power Law Exponent (v):';
            app.PowerLawExponent = uieditfield(app.DetectionParametersPanel, 'numeric');
            app.PowerLawExponent.Limits = [0 Inf];
            app.PowerLawExponent.Value = 2.5;
            app.AudioFileswavPanel = uipanel(app.DetectionTab);
            app.AudioFileswavPanel.Title = 'Audio Files (.wav)';
            app.NumberofChannelsEditFieldLabel = uilabel(app.AudioFileswavPanel);
            app.NumberofChannelsEditFieldLabel.Text = 'Number of Channels:';
            app.NumberofChannels = uieditfield(app.AudioFileswavPanel, 'numeric');
            app.NumberofChannels.Limits = [1 4];
            app.NumberofChannels.Value = 4;
            app.SelectAudioFileDirectoryButton = uibutton(app.AudioFileswavPanel, 'push');
            app.SelectAudioFileDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAudioFileDirectoryButtonPushed, true);
            app.SelectAudioFileDirectoryButton.Text = 'Select Audio File Directory';
            app.InputDirectoryLocationLabel = uilabel(app.AudioFileswavPanel);
            app.InputDirectoryLocationLabel.Text = 'Input Directory Location';
            app.PlottingParametersPanel = uipanel(app.DetectionTab);
            app.PlottingParametersPanel.Title = 'Plotting Parameters';
            app.FFTLengthEditFieldLabel = uilabel(app.PlottingParametersPanel);
            app.FFTLengthEditFieldLabel.Text = 'FFT Length:';
            app.FFTLength = uieditfield(app.PlottingParametersPanel, 'numeric');
            app.FFTLength.Limits = [0 Inf];
            app.FFTLength.Value = 512;
            app.PercentOverlapEditFieldLabel = uilabel(app.PlottingParametersPanel);
            app.PercentOverlapEditFieldLabel.Text = 'Percent Overlap:';
            app.PercentOverlap = uieditfield(app.PlottingParametersPanel, 'numeric');
            app.PercentOverlap.Limits = [0 100];
            app.PercentOverlap.Value = 90;
            app.CallParametersPanel = uipanel(app.DetectionTab);
            app.CallParametersPanel.Title = 'Call Parameters';
            app.MinCallDurationsecondsEditFieldLabel = uilabel(app.CallParametersPanel);
            app.MinCallDurationsecondsEditFieldLabel.Text = 'Min Call Duration (seconds):';
            app.MinCallDuration = uieditfield(app.CallParametersPanel, 'numeric');
            app.MinCallDuration.Limits = [0 Inf];
            app.MinCallDuration.Value = 0.2;
            app.MaxCallFrequencyHzLabel = uilabel(app.CallParametersPanel);
            app.MaxCallFrequencyHzLabel.Text = 'Max Call Frequency (Hz):';
            app.MaxCallFrequency = uieditfield(app.CallParametersPanel, 'numeric');
            app.MaxCallFrequency.Limits = [0 Inf];
            app.MaxCallFrequency.Value = 1000;
            app.MinCallFrequencyHzLabel = uilabel(app.CallParametersPanel);
            app.MinCallFrequencyHzLabel.Text = 'Min Call Frequency (Hz):';
            app.MinCallFrequency = uieditfield(app.CallParametersPanel, 'numeric');
            app.MinCallFrequency.Limits = [0 Inf];
            app.MinCallFrequency.Value = 50;
            app.MaxCallDurationsecondsLabel = uilabel(app.CallParametersPanel);
            app.MaxCallDurationsecondsLabel.Text = 'Max Call Duration (seconds):';
            app.MaxCallDuration = uieditfield(app.CallParametersPanel, 'numeric');
            app.MaxCallDuration.Limits = [0 Inf];
            app.MaxCallDuration.Value = 2;
            app.RunDetectorButton = uibutton(app.DetectionTab, 'push');
            app.RunDetectorButton.ButtonPushedFcn = createCallbackFcn(app, @RunDetectorButtonPushed, true);
            app.RunDetectorButton.Text = 'Run Detector';
            app.OutputDetectionFilesPanel = uipanel(app.DetectionTab);
            app.OutputDetectionFilesPanel.Title = 'Output Detection Files';
            app.SelectOutputDetectionFileDirectoryButton = uibutton(app.OutputDetectionFilesPanel, 'push');
            app.SelectOutputDetectionFileDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputDetectionFileDirectoryButtonPushed, true);
            app.SelectOutputDetectionFileDirectoryButton.Text = 'Select Output Detection File Directory';
            app.OutputDirectoryLocationLabel = uilabel(app.OutputDetectionFilesPanel);
            app.OutputDirectoryLocationLabel.Text = 'Output Directory Location';
            app.TheFishSenseLabel = uilabel(app.DetectionTab);
            app.TheFishSenseLabel.Text = 'The Fish Sense:';
            app.ageneralizedpowerlawdetectionalgorithmforfishsoundsLabel = uilabel(app.DetectionTab);
            app.ageneralizedpowerlawdetectionalgorithmforfishsoundsLabel.Text = {'a generalized power-law detection algorithm for fish sounds'; ''};
            app.DAQInformationFilePanel = uipanel(app.DetectionTab);
            app.DAQInformationFilePanel.Title = 'DAQ Information File';
            app.SelectDAQInformationFileButton = uibutton(app.DAQInformationFilePanel, 'push');
            app.SelectDAQInformationFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDAQInformationFileButtonPushed, true);
            app.SelectDAQInformationFileButton.Text = 'Select DAQ Information File';
            app.DAQFileLabel = uilabel(app.DAQInformationFilePanel);
            app.DAQFileLabel.Text = 'DAQ File';
            app.ReviewTab = uitab(app.TabGroup);
            app.ReviewTab.Title = 'Review';
            app.DetectionParametersPanel_2 = uipanel(app.ReviewTab);
            app.DetectionParametersPanel_2.Title = 'Detection Parameters';
            app.SampleDurationsecondsEditFieldLabel_2 = uilabel(app.DetectionParametersPanel_2);
            app.SampleDurationsecondsEditFieldLabel_2.Text = 'Sample Duration (seconds):';
            app.SampleDuration_2 = uieditfield(app.DetectionParametersPanel_2, 'numeric');
            app.SampleDuration_2.Limits = [0 Inf];
            app.SampleDuration_2.Value = 10;
            app.NoiseThresholdLabel_2 = uilabel(app.DetectionParametersPanel_2);
            app.NoiseThresholdLabel_2.Text = 'Noise Threshold (%):';
            app.NoiseThreshold_2 = uieditfield(app.DetectionParametersPanel_2, 'numeric');
            app.NoiseThreshold_2.Limits = [0 100];
            app.NoiseThreshold_2.Value = 95;
            app.PowerLawExponentvLabel_2 = uilabel(app.DetectionParametersPanel_2);
            app.PowerLawExponentvLabel_2.Text = 'Power Law Exponent (v):';
            app.PowerLawExponent_2 = uieditfield(app.DetectionParametersPanel_2, 'numeric');
            app.PowerLawExponent_2.Limits = [0 Inf];
            app.PowerLawExponent_2.Value = 2.5;
            app.AudioFileswavPanel_2 = uipanel(app.ReviewTab);
            app.AudioFileswavPanel_2.Title = 'Audio Files (.wav)';
            app.NumberofChannelsEditFieldLabel_2 = uilabel(app.AudioFileswavPanel_2);
            app.NumberofChannelsEditFieldLabel_2.Text = 'Number of Channels:';
            app.NumberofChannels_2 = uieditfield(app.AudioFileswavPanel_2, 'numeric');
            app.NumberofChannels_2.Limits = [1 4];
            app.NumberofChannels_2.Value = 4;
            app.SelectAudioFileDirectoryButton_2 = uibutton(app.AudioFileswavPanel_2, 'push');
            app.SelectAudioFileDirectoryButton_2.Text = 'Select Audio File Directory';
            app.InputDirectoryLocationLabel_2 = uilabel(app.AudioFileswavPanel_2);
            app.InputDirectoryLocationLabel_2.Text = 'Input Directory Location';
            app.PlottingParametersPanel_2 = uipanel(app.ReviewTab);
            app.PlottingParametersPanel_2.Title = 'Plotting Parameters';
            app.FFTLengthEditFieldLabel_2 = uilabel(app.PlottingParametersPanel_2);
            app.FFTLengthEditFieldLabel_2.Text = 'FFT Length:';
            app.FFTLength_2 = uieditfield(app.PlottingParametersPanel_2, 'numeric');
            app.FFTLength_2.Limits = [0 Inf];
            app.FFTLength_2.Value = 512;
            app.PercentOverlapEditFieldLabel_2 = uilabel(app.PlottingParametersPanel_2);
            app.PercentOverlapEditFieldLabel_2.Text = 'Percent Overlap:';
            app.PercentOverlap_2 = uieditfield(app.PlottingParametersPanel_2, 'numeric');
            app.PercentOverlap_2.Limits = [0 100];
            app.PercentOverlap_2.Value = 90;
            app.CallParametersPanel_2 = uipanel(app.ReviewTab);
            app.CallParametersPanel_2.Title = 'Call Parameters';
            app.MinCallDurationsecondsEditFieldLabel_2 = uilabel(app.CallParametersPanel_2);
            app.MinCallDurationsecondsEditFieldLabel_2.Text = 'Min Call Duration (seconds):';
            app.MinCallDuration_2 = uieditfield(app.CallParametersPanel_2, 'numeric');
            app.MinCallDuration_2.Limits = [0 Inf];
            app.MinCallDuration_2.Value = 0.1;
            app.MaxCallFrequencyHzLabel_2 = uilabel(app.CallParametersPanel_2);
            app.MaxCallFrequencyHzLabel_2.Text = 'Max Call Frequency (Hz):';
            app.MaxCallFrequency_2 = uieditfield(app.CallParametersPanel_2, 'numeric');
            app.MaxCallFrequency_2.Limits = [0 Inf];
            app.MaxCallFrequency_2.Value = 1000;
            app.MinCallFrequencyHzLabel_2 = uilabel(app.CallParametersPanel_2);
            app.MinCallFrequencyHzLabel_2.Text = 'Min Call Frequency (Hz):';
            app.MinCallFrequency_2 = uieditfield(app.CallParametersPanel_2, 'numeric');
            app.MinCallFrequency_2.Limits = [0 Inf];
            app.MinCallFrequency_2.Value = 50;
            app.RunDetectorButton_2 = uibutton(app.ReviewTab, 'push');
            app.RunDetectorButton_2.Text = 'Run Detector';
            app.OutputDetectionFilesPanel_2 = uipanel(app.ReviewTab);
            app.OutputDetectionFilesPanel_2.Title = 'Output Detection Files';
            app.SelectOutputDetectionFileDirectoryButton_2 = uibutton(app.OutputDetectionFilesPanel_2, 'push');
            app.SelectOutputDetectionFileDirectoryButton_2.Text = 'Select Output Detection File Directory';
            app.OutputDirectoryLocationLabel_2 = uilabel(app.OutputDetectionFilesPanel_2);
            app.OutputDirectoryLocationLabel_2.Text = 'Output Directory Location';
            app.TheFishSenseReviewLabel = uilabel(app.ReviewTab);
            app.TheFishSenseReviewLabel.Text = 'The Fish Sense Review:';
            app.manuallyreviewdetectionsfromTheFishSenseLabel = uilabel(app.ReviewTab);
            app.manuallyreviewdetectionsfromTheFishSenseLabel.Text = 'manually review detections from The Fish Sense';
            app.DAQInformationFilePanel_2 = uipanel(app.ReviewTab);
            app.DAQInformationFilePanel_2.Title = 'DAQ Information File';
            app.SelectDAQInformationFileButton_2 = uibutton(app.DAQInformationFilePanel_2, 'push');
            app.SelectDAQInformationFileButton_2.Text = 'Select DAQ Information File';
            app.DAQFileLabel_2 = uilabel(app.DAQInformationFilePanel_2);
            app.DAQFileLabel_2.Text = 'DAQ File';
            app.TheFishDriverLabel = uilabel(app.UIFigure);
            app.TheFishDriverLabel.Text = 'The Fish Driver';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TheFishDriver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end